classdef adcircAnimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCIRCAnimatorUIFigure   matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        TimeIndexSliderLabel     matlab.ui.control.Label
        TimeIndexSlider          matlab.ui.control.Slider
        GotoIndexEditFieldLabel  matlab.ui.control.Label
        GotoIndexEditField       matlab.ui.control.NumericEditField
        LOADButton               matlab.ui.control.Button
        MessagesTextAreaLabel    matlab.ui.control.Label
        MessagesTextArea         matlab.ui.control.TextArea
        BrowserButton            matlab.ui.control.Button
        ElementsButton           matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        Grid  % Description
        nc63 = 'fort.63.nc'
        nc64 = 'fort.64.nc'
        Url = ''
        ColorMin = []
        ColorMax = []
        ContourVals = 0
        handles = []
    end
    
    methods (Access = private)
        
        function results = GetNCs(app)
            disp(pwd)
            if isempty(app.Url)
                if isa(app.nc63,'ncgeodataset')
                    % already have nc objs.
                    disp('Already have ncgeodatasets.  Clear and "Go" again.')
                elseif exist(app.nc63,'file')
                        app.nc63=ncgeodataset(app.nc63);
                        app.nc64=ncgeodataset(app.nc64);
                else
                    error('fort.63,64.nc does not exist locally. Either cd to a directory, or use Url.')
                end
            else
                try
                    app.nc63=ncgeodataset([app.Url '/fort.63.nc']);
                    app.nc64=ncgeodataset([app.Url '/fort.64.nc']);
                catch ME
                    disp(['ID: ' ME.identifier])
                    throw(ME)
                end
            end      
            disp('Loaded fort.63.nc and fort.64.nc')
        end
        
        function results = GetTime(app)
            time=app.nc63.time('time');
            if isempty(time)
                error('time in fort.63.nc is length 0.  This is pointless.')
            end
            time=datetime(datevec(time));
            app.nTimes=size(time,1);
            t=time;
            if app.StartingTime ~= datetime(0,0,0)
                t=t-t(1);
                t=t+app.StartingTime;
            end
            app.time=t;
        end
        
        function results = InitFig(app)
            %app.fign=figure('Position',app.FigurePosition);
            %drawelems(g,'Color',[1 1 1]*.7,'Linewidth',.25);
            plotbnd(app.Grid,'LineWidth',.2,'Color','m');
            hc=lcontour(app.Grid,'z',app.ContourVals,'Color','k','LineWidth',1);
            if isgraphics(hc)
                set_height(hc,1);
            end
            %    plotcoast('states')
            grid on
            grid minor
            %hc=lcontour(g,'z',[2:8],'Color','r','LineWidth',.2);
            %set_height(hc,1);
            %hc=lcontour(g,'z',-[2:10],'Color','b','LineWidth',.2);
            %set_height(hc,1);
            axis('equal')
            
%             if exist(obj.ScriptToAdd,'file')
%                 eval(obj.ScriptToAdd)
%             end
            
%             if ~(isempty(obj.ColorMin) && isempty(obj.ColorMax))
%                 set(gca,'CLim',[obj.ColorMin obj.ColorMax])
%             end
%             colormap(obj.ColorMap)
            colorbar
            
            %obj=set_ax_lims(obj);
            
        end
        
        function results = GetGrid(app)
            %if isempty(app.Grid)
                app.Grid=ExtractGrid(app.nc63);
            %end
        end
        
        function results = PlotBnd(app)
            plotbnd(app.Grid,'Axes',app.UIAxes,'LineWidth',.2,'Color','m')
            axis(app.UIAxes,'equal')
        end
        
        function results = DrawContours(app)
            lcontour(app.Grid,'z',app.ContourVals,'Axes',app.UIAxes,'Color','k','LineWidth',1)
        end
        
        function results = DrawFrame(app)
            % get index in Goto Index
            idx=app.GotoIndexEditField.Value;
            app.MessagesTextArea.Value=string(idx);
            app.handles.colormesh2d = DrawSurf(app);
        end
        function h = DrawSurf(app)
            h=colormesh2d(app.Grid,app.nc63{'zeta'}(app.GotoIndexEditField.Value,:),'Axes',app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ElementsButtonValueChanged(app, event)
            value = app.ElementsButton.Value;
            % toggle grid elements
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            try 
                app.GetNCs()
            catch ME
                app.MessagesTextArea.Value=sprintf("Current directory = %s.  fort.63,64.nc does not exist locally. Either cd to a directory, or use Url.",pwd);
                return
            end
            app.GetGrid()
            app.PlotBnd()
            app.DrawContours()
            app.DrawFrame()
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'tight')
            colorbar
        end

        % Button pushed function: BrowserButton
        function BrowserButtonPushed(app, event)
            selpath=uigetdir;
            if exist(selpath,'dir')
                cd(selpath)
            end
            app.MessagesTextArea.Value=pwd;
        end

        % Value changed function: ElementsButton
        function ElementsButtonValueChanged2(app, event)
            value = app.ElementsButton.Value;
            app.Grid
            % if element obj exists, delete. otherwise plot it
            s=findobj(app.UIAxes,'Tag','elements');
            if isempty(s)
                drawelems(app.Grid,'Axes',app.UIAxes)
            else
                delete(s)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADCIRCAnimatorUIFigure = uifigure('Visible', 'off');
            app.ADCIRCAnimatorUIFigure.Name = 'ADCIRC Animator';
            app.UIAxes = uiaxes(app.ADCIRCAnimatorUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Lon')
            ylabel(app.UIAxes, 'Lat')
            app.TimeIndexSliderLabel = uilabel(app.ADCIRCAnimatorUIFigure);
            app.TimeIndexSliderLabel.Text = 'Time Index';
            app.TimeIndexSlider = uislider(app.ADCIRCAnimatorUIFigure);
            app.GotoIndexEditFieldLabel = uilabel(app.ADCIRCAnimatorUIFigure);
            app.GotoIndexEditFieldLabel.Text = {'Goto Index'; ''};
            app.GotoIndexEditField = uieditfield(app.ADCIRCAnimatorUIFigure, 'numeric');
            app.GotoIndexEditField.Value = 1;
            app.LOADButton = uibutton(app.ADCIRCAnimatorUIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.MessagesTextAreaLabel = uilabel(app.ADCIRCAnimatorUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages:';
            app.MessagesTextArea = uitextarea(app.ADCIRCAnimatorUIFigure);
            app.BrowserButton = uibutton(app.ADCIRCAnimatorUIFigure, 'push');
            app.BrowserButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.BrowserButton.Text = {'Browser'; ''};
            app.ElementsButton = uibutton(app.ADCIRCAnimatorUIFigure, 'state');
            app.ElementsButton.ValueChangedFcn = createCallbackFcn(app, @ElementsButtonValueChanged2, true);
            app.ElementsButton.Text = {'Elements'; ''};
            % Show the figure after all components are created
            app.ADCIRCAnimatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adcircAnimator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCIRCAnimatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCIRCAnimatorUIFigure)
        end
    end
end