classdef adcircAnimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCIRCAnimatorV09UIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        VectorPropertiesPanel         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ScaleEditFieldLabel           matlab.ui.control.Label
        ScaleEditField                matlab.ui.control.EditField
        StrideEditFieldLabel          matlab.ui.control.Label
        StrideEditField               matlab.ui.control.EditField
        VectorColorLabel              matlab.ui.control.Label
        VectorColorDropDown           matlab.ui.control.DropDown
        MaxinViewTextArea_2Label      matlab.ui.control.Label
        MaxinViewTextArea_2           matlab.ui.control.TextArea
        VectorVisibilitySwitch        matlab.ui.control.ToggleSwitch
        VectorDeleteButton            matlab.ui.control.Button
        ScalarPropertiesPanel         matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ColormapLabel                 matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        NumColorsLabel                matlab.ui.control.Label
        NumColorsEditField            matlab.ui.control.NumericEditField
        MaxinViewTextAreaLabel        matlab.ui.control.Label
        MaxinViewTextArea             matlab.ui.control.TextArea
        MininViewTextAreaLabel        matlab.ui.control.Label
        MininViewTextArea             matlab.ui.control.TextArea
        ColorMinEditField_2Label      matlab.ui.control.Label
        ColorMinEditField_2           matlab.ui.control.NumericEditField
        ColorMaxEditField_2Label      matlab.ui.control.Label
        ColorMaxEditField_2           matlab.ui.control.NumericEditField
        AutoColorOffButton            matlab.ui.control.StateButton
        SurfaceVisibilitySwitch       matlab.ui.control.ToggleSwitch
        SurfaceDeleteButton           matlab.ui.control.Button
        MessagesLabel                 matlab.ui.control.Label
        CurrentSolutionTextAreaLabel  matlab.ui.control.Label
        MessagesTextArea              matlab.ui.control.TextArea
        CurrentSolutionTextArea       matlab.ui.control.TextArea
        GridPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ElementsLabel                 matlab.ui.control.Label
        ElementsSwitch                matlab.ui.control.ToggleSwitch
        ContoursLabel                 matlab.ui.control.Label
        ContoursSwitch                matlab.ui.control.Switch
        NumNodesSwitchLabel           matlab.ui.control.Label
        NumNodesSwitch                matlab.ui.control.ToggleSwitch
        NumElemsSwitchLabel           matlab.ui.control.Label
        NumElemsSwitch                matlab.ui.control.ToggleSwitch
        NumBathySwitchLabel           matlab.ui.control.Label
        NumBathySwitch                matlab.ui.control.ToggleSwitch
        DepthValuesEditField          matlab.ui.control.EditField
        DepthValuesLabel              matlab.ui.control.Label
        ClearContoursButton           matlab.ui.control.Button
        GotoGlobalMaxButton           matlab.ui.control.Button
        GotoMaxInViewButton           matlab.ui.control.Button
        TimeSeriesPanel               matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        SampleSolutionCheckBox        matlab.ui.control.CheckBox
        LegendLabel                   matlab.ui.control.Label
        LegendSwitch                  matlab.ui.control.ToggleSwitch
        StatusLabel                   matlab.ui.control.Label
        FrameControlPanel             matlab.ui.container.Panel
        BackwardButton                matlab.ui.control.Button
        ForwardButton                 matlab.ui.control.Button
        GOButton                      matlab.ui.control.StateButton
        TimeStrideEditFieldLabel      matlab.ui.control.Label
        TimeStrideEditField           matlab.ui.control.NumericEditField
        CurrentIndexEditFieldLabel    matlab.ui.control.Label
        CurrentIndexEditField         matlab.ui.control.NumericEditField
        TImeIndexPanel                matlab.ui.container.Panel
        TimeIndexLabel                matlab.ui.control.Label
        TimeIndexSlider               matlab.ui.control.Slider
        Panel_2                       matlab.ui.container.Panel
        GridNameTextAreaLabel         matlab.ui.control.Label
        GridNameTextArea              matlab.ui.control.TextArea
        elementsTextAreaLabel         matlab.ui.control.Label
        elementsTextArea              matlab.ui.control.TextArea
        nodesLabel                    matlab.ui.control.Label
        nodesTextArea                 matlab.ui.control.TextArea
        TimeStepEditFieldLabel        matlab.ui.control.Label
        TimeStepEditField             matlab.ui.control.EditField
        RNDAYTextAreaLabel            matlab.ui.control.Label
        RNDAYTextArea                 matlab.ui.control.TextArea
        StatusLamp                    matlab.ui.control.Lamp
        GridLayout6                   matlab.ui.container.GridLayout
        LOADButton                    matlab.ui.control.Button
        AxisEqualButton               matlab.ui.control.Button
        AxisTightButton               matlab.ui.control.Button
        DrawFrameButton               matlab.ui.control.Button
        GMapButton                    matlab.ui.control.Button
        BrowserButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        Grid  % Description
        fnc61 = 'fort.61.nc'
        fnc63 = 'fort.63.nc'
        fnc64 = 'fort.64.nc'
        fnc72 = 'fort.72.nc'
        nc61 = []
        nc63 = [] 
        nc64 = [] 
        nc72 = []
        Url = './'
        ColorMin = []
        ColorMax = []
        ColorPriorState = [NaN NaN]
        ContourVals = [0 10  25  50  100]
        handles = {}
        surfaceVariable = 'zeta'
        vectorVariable_u = 'u-vel'
        vectorVariable_v = 'v-vel'
%        vectorVariable_u = 'windx'
%        vectorVariable_v = 'windy'
        nTimes = []
        StartingTime = datetime(0,0,0)
        IterStart  = 1 
        IterStride = 1
        IterStop   = Inf 
        time = [] 
        autoLoad = true
        colormesh2d_handle = [] 
        boundary_handle = [] 
        elements_handle = [] 
        vecplot_handle = []
        colormap = jet(24)
        vector_properties = {}
        handle_zoom = []
        contours_handle = []
        numelemtext_handle = []
        numnodestext_handle = []
        numbathytext_handle = []
        gmap_handle = []
        time_series_figs = {}
        ColOrd = jet(8)
    end
    
    methods (Access = private)
        
        function results = GetNCs(app)
            app.MessagesTextArea.Value = ['Loading nc files in ' app.Url ];
            app.nc63 = ncgeodataset([app.Url '/' app.fnc63]);
            app.nc64 = ncgeodataset([app.Url '/' app.fnc64]);
%             try 
%                 disp('here')
%                 app.nc63=ncgeodataset(app.nc63);
%                 app.nc64=ncgeodataset(app.nc64);
% 
%             catch ME
%                 disp(['ID: ' ME.identifier])
%                 throw(ME)
%                 %error(ME)
%             end
%             app.nc63
%             app.nc63=ncgeodataset(app.nc63);
%             app.nc64=ncgeodataset(app.nc64);
%            if isempty(app.Url)
%                 if isa(app.nc63,'ncgeodataset')
%                     % already have nc objs.
%                     disp('Already have ncgeodatasets.  Clear and "Go" again.')
%                 elseif exist(app.nc63,'file')
%                         app.nc63=ncgeodataset(app.nc63);
%                         app.nc64=ncgeodataset(app.nc64);
% %                 else
%                     error('fort.63,64.nc does not exist locally. Either cd to a directory, or use Url.')
% %                 end
% %            else
%                 try
%                     app.nc63=ncgeodataset([app.Url '/fort.63.nc']);
%                     app.nc64=ncgeodataset([app.Url '/fort.64.nc']);
%                 catch ME
%                     disp(['ID: ' ME.identifier])
%                     throw(ME)
%                 end
%             %end      
%             app.MessagesTextArea.Value='Loaded fort.63.nc and fort.64.nc';
%            GetTimes(app)
        end
        
        function results = GetTimes(app)
            app.MessagesTextArea.Value = 'Getting/setting times.';
            time = app.nc63.time('time'); %#ok<*ADPROP> 
            if isempty(time)
                error('time in fort.63.nc is length 0.  This is pointless.')
            end
            time = datetime(datevec(time));
            app.nTimes = size(time,1);
            t = time;
            if app.StartingTime ~= datetime(0,0,0)
                t = t - t(1);
                t = t + app.StartingTime;
            end
            app.time=t;
            % set the slider min/max indices
            app.TimeIndexSlider.Limits = [1 app.nTimes];
            app.IterStop = app.nTimes;
            app.CurrentIndexEditField.Value = 1;
            app.TimeIndexSlider.Value = 1;
            dt=time(2)-time(1);
            app.TimeStepEditField.Value=sprintf('%d sec',seconds(dt));
            app.RNDAYTextArea.Value=char(time(1));
            app.SetTitle()
        end
        
        function results = InitFrame(app)
            app.MessagesTextArea.Value = 'Initializing main axes.';
            %app.fign=figure('Position',app.FigurePosition);
            axis(app.UIAxes,'equal')
            axis(app.UIAxes,'tight')
            SetColorLimits(app)
            SetColormap(app)
            % obj=set_ax_lims(obj);
        end
        
        function SetColormap(app)
            colormap(app.UIAxes,app.colormap);
            colorbar(app.UIAxes)
        end
        
        function results = SetColorLimits(app)
            cmax = app.ColorMaxEditField_2.Value;
            cmin = app.ColorMinEditField_2.Value;            
            set(app.UIAxes,'CLim',[cmin cmax])
            app.ColorPriorState=caxis(app.UIAxes);
%             
%                  FieldMax=ceil(maxThisData/ColorIncrement)*ColorIncrement;
%      FieldMin=floor(minThisData/ColorIncrement)*ColorIncrement;
% 
%      set(Handles.CMax,'String',sprintf('%.2f',FieldMax))
%      set(Handles.CMin,'String',sprintf('%.2f',FieldMin))
%      set(Handles.NCol,'String',sprintf('%d',NumberOfColors))
%      PossibleMaps=cellstr(get(Handles.ColormapSetter,'String'));
%      CurrentValue=get(Handles.ColormapSetter,'Value');
%      CurrentMap=PossibleMaps{CurrentValue};
%      cmap=eval(sprintf('%s(%d)',CurrentMap,NumberOfColors));
%      CLim([FieldMin FieldMax])
%      colormap(cmap)

        end
        
        function results = GetGrid(app)
            app.MessagesTextArea.Value = 'Getting grid.';
            app.Grid = ExtractGrid(app.nc63);
            app.GridNameTextArea.Value=app.Grid.name;
            app.nodesTextArea.Value=sprintf('%d',app.Grid.nn);
            app.elementsTextArea.Value=sprintf('%d',app.Grid.ne);
        end
        
        function PlotBnd(app)
            app.MessagesTextArea.Value = 'Drawing boundary.';
            app.boundary_handle = plotbnd(app.Grid,'Axes',app.UIAxes,'LineWidth',.2,'Color','m','Clipping','on');
        end
        
        function results = DrawContours(app)
            app.MessagesTextArea.Value = 'Drawing contours.';
            temp=app.DepthValuesEditField.Value;
            temp=split(temp);
            for i=1:length(temp)
                if ~isempty(temp{i})
                    cvals(i)=str2double(temp{i});
                end
            end
            app.contours_handle = lcontour(app.Grid,'z',cvals,'Axes',app.UIAxes,'Color','k','LineWidth',1);
            app.ContoursSwitch.Value = 'On';
            app.MessagesTextArea.Value = 'Done.';

        end
        
        function results = DrawFrame(app)
            idx = app.CurrentIndexEditField.Value;
            app.MessagesTextArea.Value = string(idx);
            % check show/hide status
            if strcmp(app.SurfaceVisibilitySwitch.Value,'Show')   
                app.colormesh2d_handle = DrawSurf(app);
            end
            if strcmp(app.VectorVisibilitySwitch.Value,'Show')
                 app.vecplot_handle = DrawVec(app);
            end    
            % check to see what things have been toggled. 
            app.NumElemsSwitchValueChanged();
            app.NumNodesSwitchValueChanged();
        end
    
        function h = DrawSurf(app)
            app.MessagesTextArea.Value = 'Drawing surface.';
            if ishandle(app.colormesh2d_handle)
                delete(app.colormesh2d_handle)
            end
            if ~isa(app.nc63,'ncgeodataset')
                error('app.nc63 not a geodataset.')
            end
            s = app.nc63{app.surfaceVariable}(app.CurrentIndexEditField.Value,:);
            h = colormesh2d(app.Grid,s,'Axes',app.UIAxes);
            %h = colormesh2d(app.Grid,app.nc64{'v-vel'}(app.CurrentIndexEditField.Value,:),'Axes',app.UIAxes);
            h.Clipping = 'on';
            % get the viewable region from the current axes
            nodes_in_reg = GetNodesInView(app);
            maxreg = max(s(nodes_in_reg));
            app.MaxinViewTextArea.Value = sprintf('%.3f m',maxreg);
            minreg = min(s(nodes_in_reg));
            app.MininViewTextArea.Value = sprintf('%.3f m',minreg);
            app.MessagesTextArea.Value = 'Done.';

        end
        
        function h = DrawVec(app)
             % app.MessagesTextArea.Value = 'Drawing vectors.';
             drawnow
             if ishandle(app.vecplot_handle)
                 delete(app.vecplot_handle)
             end
             u = app.nc64{app.vectorVariable_u}(app.CurrentIndexEditField.Value,:);
             u = u(:);
             v = app.nc64{app.vectorVariable_v}(app.CurrentIndexEditField.Value,:);
             v = v(:);
             vsca = str2double(app.ScaleEditField.Value);
             vstr = str2double(app.StrideEditField.Value);
             vcol = app.VectorColorDropDown.Value;
             h = vecplot(app.Grid.x,app.Grid.y,u,v,'Axes',app.UIAxes,...
                 'ScaleLabel','no scale',...
                 'Clipping','on',...
                 'ScaleFac',vsca,'Stride',vstr,'Color',vcol);
             h.ZData=10*ones(size(h.XData));
             if ~isempty(h)
                 h.Clipping = 'on';
             end
             spd = sqrt(u.^2 + v.^2);
             % get the viewable region from the current axes
             nodes_in_reg = GetNodesInView(app);
             maxreg = max(spd(nodes_in_reg));
             app.MaxinViewTextArea_2.Value = sprintf('%.2f m/s',maxreg);
        end
        
        function results = SetStatusReady(app)
            app.StatusLamp.Color = 'g';
        end
        
        function results = SetStatusBusy(app)
            app.StatusLamp.Color = 'r';
            pause(.0001)
        end
        
        function results = Animate(app)
            app.IterStart = app.CurrentIndexEditField.Value;
            app.IterStride = app.TimeStrideEditField.Value;
            SetStatusBusy(app)
            app.MessagesTextArea.Value = 'Animating ...';

            for it =  app.IterStart:app.IterStride:app.IterStop
                app.DrawFrame()
                app.CurrentIndexEditField.Value = it;
                app.TimeIndexSlider.Value = it;
                app.RNDAYTextArea.Value=char(app.time(it));
                % set title
                app.SetTitle()
                drawnow
                % check value of "GO" button
                if ~app.GOButton.Value
                    app.MessagesTextArea.Value = 'Animation paused ...';
                    break
                end
            end
            SetStatusReady(app)
        end
        
        function results = DrawElems(app)
            app.elements_handle = drawelems(app.Grid,'Axes',app.UIAxes);
            app.elements_handle.LineWidth = .25;
            app.elements_handle.Color = [1 1 1]*.6;
        end
        
%         function results = NOAA(app)
%             ncol = app.NumColorsEditField.value;
%             results = noaa_cmap(ncol);
%         end
        
        function nodes_in_reg = GetNodesInView(app)
             % get the viewable region from the current axes
             XLim = get(app.UIAxes,'XLim');
             YLim = get(app.UIAxes,'YLim');
             Px = [XLim(1) XLim(2) XLim(2) XLim(1) XLim(1)];
             Py = [YLim(1) YLim(1) YLim(2) YLim(2) YLim(1)];
             nodes_in_reg = inpolygon(app.Grid.x,app.Grid.y,Px,Py); 
        end     
        
        function results = SampleSolution(app,event)
            % determine of point is in axes
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'colorsurf')
                % disp('colorsurf clicked!!')
                % get point
                cp = app.UIAxes.CurrentPoint;
                % find nearest node
                j = findelem(app.Grid,cp(1),cp(3));
                if isnan(j)
                    app.MessagesTextArea.Value = 'Point not in grid.  Try again...';
                    return
                end
                i = app.Grid.e(j,1);
                if isempty(app.time_series_figs)
                    app.time_series_figs = figure;
                    p = app.time_series_figs.Position;
                    app.time_series_figs.Position = [p(1) p(2) 600 300];
                    app.time_series_figs.Name = 'Time Series';
                    tsaxes=axes;
                else
                    tsaxes = findobj(app.time_series_figs.Children,'Type','Axes');
                end
                z = app.nc63{app.surfaceVariable}(:,i);
                figure(app.time_series_figs)
                %ColOrd = get(app.UIAxes,'ColorOrder');
                ic = rem(length(tsaxes.Children)+1,length(app.ColOrd))+1;
                line(app.time,z,'DisplayName',sprintf('%s @ %d',app.surfaceVariable,i),'Color',app.ColOrd(ic,:))
                legend
                tsaxes.XMinorGrid = 'on';
                tsaxes.YMinorGrid = 'on';
                % force figure to front
            end
        end
        
        function results = SetTitle(app)
            str=app.RNDAYTextArea.Value;
            title(app.UIAxes, str)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, url)
            
            %app.ADCIRCAnimatorV09UIFigure.CreateFcn='';
            %app.UIAxes.Toolbar.Visible = 'off'; 
            %zoom(app.UIAxes,'on');
            
            % check for nctoolbox
            if ~exist('ncgeodataset','file')
                error('Ncgeodataset not found. Ternimal.')
            end
            
            if ~exist('url','var')
                if ~exist('fort.63.nc','file') && ~exist('fort.64.nc','file')
                    error('fort.63.nc and fort.64.nc must exist in the current working directory.')
                end
            else
                app.Url=url;
            end
            
            [tb,btns] = axtoolbar(app.UIAxes,{'zoomin','zoomout','restoreview','pan'});
            if app.autoLoad
                LOADButtonPushed(app)
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            SetStatusBusy(app)
            app.CurrentSolutionTextArea.Value = app.Url;
            app.MessagesTextArea.Value = sprintf('Loading ADCIRC files in %s.',pwd);
            app.GetNCs()
%             try 
%                 app.MessagesTextArea.Value = sprintf("Loading fort.63,64.nc in %s...",pwd);
%                 app.GetNCs()
%             catch ME
%                 app.MessagesTextArea.Value = sprintf("Current directory = %s. fort.63,64.nc does not exist locally. Either cd to a directory, or use Url.",pwd);
%                 return
%             end
            cla(app.UIAxes)
            app.GetGrid()
            app.InitFrame()
            app.PlotBnd()
            app.DrawContours()
            app.GetTimes()
            app.DrawFrame()
            %app.CurrentSolutionTextArea.Value = pwd;
            
            % enable functions
            app.GOButton.Enable = 'on';
            app.ForwardButton.Enable = 'on';
            app.BackwardButton.Enable = 'on';
            SetStatusReady(app)
            app.MessagesTextArea.Value = sprintf('Ready...');

        end

        % Button pushed function: BrowserButton
        function BrowserButtonPushed(app, event)
            selpath = uigetdir;
            if exist(selpath,'dir')
                cd(selpath)
            end
            app.MessagesTextArea.Value = pwd;
            % need to reset app and reload
            app.LOADButtonPushed()
        end

        % Value changed function: ElementsSwitch
        function ElementsButtonValueChanged2(app, event)

            SetStatusBusy(app)
            h = findobj(app.UIAxes,'Tag','elements');
            if ishandle(h)
                delete(h)
            else
                app.MessagesTextArea.Value = 'Drawing Elements.';
                app.DrawElems()
            end
            SetStatusReady(app)
        end

        % Button pushed function: DrawFrameButton
        function DrawFrameButtonPushed(app, event)
            
            %disp('inside DrawFrameButtonPushed')
            SetStatusBusy(app)
            app.DrawFrame()
            SetStatusReady(app)
            
        end

        % Value changed function: TimeIndexSlider
        function TimeSliderIndexEditFieldValueChanged(app, event)
            
            value = app.TimeIndexSlider.Value;
            app.CurrentIndexEditField.Value=floor(value);
            SetStatusBusy(app)
            app.DrawFrame()
            app.RNDAYTextArea.Value=char(app.time(floor(value)));
            app.SetTitle()
            SetStatusReady(app)
            
        end

        % Value changed function: GOButton
        function GOButtonValueChanged(app, event)
            value = app.GOButton.Value;
            % if 1, start animation, else stop
            if value
                app.GOButton.BackgroundColor='r';
                app.GOButton.Text='STOP';
                
                app.IterStart=app.CurrentIndexEditField.Value;
                app.IterStride=app.TimeStrideEditField.Value;
                
                app.Animate()
            else
                app.GOButton.BackgroundColor='g';
                app.GOButton.Text='GO';
            end
        end

        % Value changed function: VectorColorDropDown
        function VectorColorDropDownValueChanged(app, event)
            value = app.VectorColorDropDown.Value;
            set(app.vecplot_handle,'Color',value)
        end

        % Button pushed function: AxisEqualButton
        function AxisEqualButtonPushed(app, event)
            axis(app.UIAxes,'equal')
        end

        % Button pushed function: AxisTightButton
        function AxisTightButtonPushed(app, event)
            axis(app.UIAxes,'tight')
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % increment frame
            value = app.CurrentIndexEditField.Value;
            stride=app.TimeStrideEditField.Value;
            val=value+stride;
            if val > app.nTimes
                val=app.nTimes;
                app.MessagesTextArea.Value = 'End of series reached.';
            end

            app.DrawFrame()
            app.CurrentIndexEditField.Value=val;
            app.TimeIndexSlider.Value=val;
            app.RNDAYTextArea.Value = char(app.time(val));
            app.SetTitle()
        end

        % Value changed function: ColormapDropDown, 
        % NumColorsEditField
        function ColormapDropDownValueChanged(app, event)
            cmapname = app.ColormapDropDown.Value;
            ncol = app.NumColorsEditField.Value;
            if strcmp(cmapname(end-2:end),'cpt') || strcmp(cmapname(end-2:end),'act')
                cmap=cpt2mat(cmapname,ncol);
            else
                cmap=eval(sprintf('%s(%d)',cmapname,ncol));
            end
            app.colormap=cmap;
            SetColormap(app)
        end

        % Value changed function: ScaleEditField, StrideEditField
        function ScaleEditFieldValueChanged(app, event)
            % value = app.ScaleEditField.Value;
            % app.VectorPropertiesPanel
            app.vecplot_handle = DrawVec(app);
        end

        % Callback function
        function ZoomOnButtonValueChanged(app, event)
            value = app.ZoomOnButton.Value;
            if value
                app.ZoomOnButton.Text='Zoom off';
                zoom(app.UIAxes,'on'); 
            else
                app.ZoomOnButton.Text='Zoom on'; 
                zoom(app.UIAxes,'off'); 
            end
        end

        % Value changed function: CurrentIndexEditField
        function CurrentIndexEditFieldValueChanged(app, event)
            value = app.CurrentIndexEditField.Value;
            app.TimeIndexSlider.Value=floor(value);
            SetStatusBusy(app)
            app.DrawFrame()
            SetStatusReady(app)
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            % decrement frame
            value = app.CurrentIndexEditField.Value;
            stride = app.TimeStrideEditField.Value;
           
            val=value-stride;
            if val < 1
                val=1;
                app.MessagesTextArea.Value = 'Start of series reached.';
            end
            
            app.DrawFrame()
            app.CurrentIndexEditField.Value = val;
            app.TimeIndexSlider.Value = val;
            app.RNDAYTextArea.Value = char(app.time(val));
            app.SetTitle()
        end

        % Value changed function: ContoursSwitch
        function ContoursSwitchValueChanged(app, event)
            cont_objs=findobj(app.UIAxes,'Tag','contour');
            if strcmp(app.ContoursSwitch.Value,'On')
                set(cont_objs,'Visible','on')
            else
                set(cont_objs,'Visible','off')
            end
        end

        % Value changed function: ColorMaxEditField_2, 
        % ColorMinEditField_2
        function ColorMaxEditFieldValueChanged(app, event)
             SetColorLimits(app)
        end

        % Value changed function: DepthValuesEditField
        function DepthValuesEditFieldValueChanged(app, event)
            value = app.DepthValuesEditField.Value;
            app.DrawContours()
        end

        % Value changed function: NumElemsSwitch
        function NumElemsSwitchValueChanged(app, event)
            % app.MessagesTextArea.Value = 'Drawing Element numbers.';
            SetStatusBusy(app)
            value = app.NumElemsSwitch.Value;
            if  strcmp(value,'Off')
                if ishandle(app.numelemtext_handle)
                    delete(app.numelemtext_handle)
                end
            else
                app.numelemtext_handle=numelems(app.Grid,'Axes',app.UIAxes);
            end
            SetStatusReady(app)
          
%             h = findobj(app.UIAxes,'Tag','elements');
%             if ishandle(h)
%                 delete(h)
%             else
%                 app.DrawElems()
%             end
%        
            
        end

        % Value changed function: NumNodesSwitch
        function NumNodesSwitchValueChanged(app, event)
            % app.MessagesTextArea.Value = 'Drawing Node numbers.';
            SetStatusBusy(app)
            value = app.NumNodesSwitch.Value;
            if strcmp(value,'Off')
                if ishandle(app.numnodestext_handle)
                    delete(app.numnodestext_handle)
                end
            else
                app.numnodestext_handle = numnodes(app.Grid,'Axes',app.UIAxes,...
                    'HorizontalAlignment','left');
            end
            SetStatusReady(app)
        end

        % Value changed function: NumBathySwitch
        function NumBathySwitchValueChanged(app, event)
            value = app.NumBathySwitch.Value;
            SetStatusBusy(app)
            value = app.NumBathySwitch.Value;
            if strcmp(value,'Off')
                if ishandle(app.numbathytext_handle)
                    delete(app.numbathytext_handle)
                end
            else
                app.numbathytext_handle = numscal(app.Grid.x,app.Grid.y,app.Grid.z,'Axes',app.UIAxes,...
                    'HorizontalAlignment','right','FontWeight','bold',...
                    'FontAngle','italic');
            end
            SetStatusReady(app)
            
        end

        % Value changed function: AutoColorOffButton
        function AutoColorOffButtonValueChanged(app, event)
            value = app.AutoColorOffButton.Value;
            if ~value
                app.AutoColorOffButton.Text = 'Auto Color On';
                app.ColorPriorState = caxis(app.UIAxes);
                app.UIAxes.CLimMode = 'auto';
                temp = caxis(app.UIAxes);
                app.ColorMinEditField_2.Value = temp(1);
                app.ColorMaxEditField_2.Value = temp(2);
            else
                app.AutoColorOffButton.Text = 'Auto Color Off';
                caxis(app.UIAxes,app.ColorPriorState)
                temp = caxis(app.UIAxes);
                app.ColorMinEditField_2.Value = temp(1);
                app.ColorMaxEditField_2.Value = temp(2);

            end
        end

        % Button pushed function: ClearContoursButton
        function ClearContoursButtonPushed(app, event)
             app.contours_handle=findobj(app.UIAxes,'Tag','contour');
             delete(app.contours_handle)
        end

        % Button pushed function: GMapButton
        function GMapButtonValueChanged(app, event)
            value = app.GMapButton.Value;
            if isempty(app.gmap_handle)
                if value
                    app.gmap_handle=plot_google_map('axis',app.UIAxes,'MapType','hybrid');
                else
                    set(app.gmap_handle,'Visible','off')
                    %app.gmap_handle.Visible='off';
                end
            else
                if value
                    set(app.gmap_handle,'Visible','on')
                else
                    set(app.gmap_handle,'Visible','off')
                end
            end
        end

        % Button pushed function: GotoGlobalMaxButton
        function GotoGlobalMaxButtonPushed(app, event)
            app.MessagesTextArea.Value = 'Going to global max water level.';
            SetStatusBusy(app)
            
            % find max val at this time level.
            z=app.colormesh2d_handle.CData;
            if range(z(:))<1e-4
                app.MessagesTextArea.Value = 'Range too small to display.  Advance the time to get to more action in the solution.';
            else
                emax=find(max(max(z)));
                ax=app.Grid.x(app.Grid.e(emax,1));
                ay=app.Grid.y(app.Grid.e(emax,1));
                axis(app.UIAxes,[ax+[-.1 .1] ay+[-.1 .1]])
            end
            SetStatusReady(app)

        end

        % Value changed function: SampleSolutionCheckBox
        function SampleSolutionCheckBoxValueChanged(app, event)
            value = app.SampleSolutionCheckBox.Value;
            
             if value
%                 % set buttondown
%                 temp=get(app.ADCIRCAnimatorV09UIFigure,'WindowButtonUpFcn');
                 app.MessagesTextArea.Value = 'Click on solution (colorsurf object) in main axes';
%                 %set(app.ADCIRCAnimatorV09UIFigure,'WindowButtonUpFcn',@SampleSolution(app)));
%                 app.ADCIRCAnimatorV09UIFigure.WindowButtonUpFcn = [];
%                 % createCallbackFcn(app, @SampleSolution, true);
%                 
%                 % [X,Y] = ginput(1)
%                 %waitforbuttonpress;
%                 %seltype=get(app.ADCIRCAnimatorV09UIFigure,'SelectionType');
%                 %if ~strcmp(seltype,'normal')break,end
             else
                 app.MessagesTextArea.Value = 'Resume normal button functions';
             end
            
        
        end

        % Window button up function: ADCIRCAnimatorV09UIFigure
        function ADCIRCAnimatorV09UIFigureWindowButtonUp(app, event)
            % Determine what conditions exist to enable the right response
            if app.SampleSolutionCheckBox.Value
                % WindowButtonUp with "SampleSolution" set. 
                % enable time series plots
                app.SampleSolution(event)
            else
                app.DrawFrame()
            end
        end

        % Value changed function: LegendSwitch
        function LegendSwitchValueChanged(app, event)
            %value = app.LegendSwitch.Value;
            % find time series figure
            % hide/unhide legend
            if ~isempty(app.time_series_figs)          
                tsaxes = findobj(app.time_series_figs.Children,'Type','Axes');
                legend(tsaxes,'toggle')
            end
        end

        % Value changed function: SurfaceVisibilitySwitch
        function SurfaceVisibilitySwitchValueChanged(app, event)
            if ishandle(app.colormesh2d_handle)
            if strcmp(app.SurfaceVisibilitySwitch.Value,'Show')
               set(app.colormesh2d_handle,'Visible','on') 
            else
               set(app.colormesh2d_handle,'Visible','off') 
            end
            end
        end

        % Value changed function: VectorVisibilitySwitch
        function VectorVisibilitySwitchValueChanged(app, event)
            %value = app.HideShowSwitch.Value;
            if ishandle(app.vecplot_handle)
            if strcmp(app.VectorVisibilitySwitch.Value,'Show')
               app.vecplot_handle.Visible='on'; 
            else
               app.vecplot_handle.Visible='off'; 
            end
            end
        end

        % Button pushed function: SurfaceDeleteButton
        function SurfaceDeleteButtonPushed(app, event)
            delete(app.colormesh2d_handle)
        end

        % Button pushed function: VectorDeleteButton
        function VectorDeleteButtonPushed(app, event)
            delete(app.vecplot_handle)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADCIRCAnimatorV09UIFigure = uifigure('Visible', 'off');
            app.ADCIRCAnimatorV09UIFigure.Name = 'ADCIRC Animator V0.9';
            app.ADCIRCAnimatorV09UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @ADCIRCAnimatorV09UIFigureWindowButtonUp, true);
            app.GridLayout = uigridlayout(app.ADCIRCAnimatorV09UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Lon')
            ylabel(app.UIAxes, 'Lat')
            app.VectorPropertiesPanel = uipanel(app.GridLayout);
            app.VectorPropertiesPanel.Title = 'Vector Properties';
            app.GridLayout3 = uigridlayout(app.VectorPropertiesPanel);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout3);
            app.ScaleEditFieldLabel.Text = {'Scale : '; ''};
            app.ScaleEditField = uieditfield(app.GridLayout3, 'text');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = '1';
            app.StrideEditFieldLabel = uilabel(app.GridLayout3);
            app.StrideEditFieldLabel.Text = 'Stride : ';
            app.StrideEditField = uieditfield(app.GridLayout3, 'text');
            app.StrideEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.StrideEditField.Value = '10';
            app.VectorColorLabel = uilabel(app.GridLayout3);
            app.VectorColorLabel.Text = 'Vector Color : ';
            app.VectorColorDropDown = uidropdown(app.GridLayout3);
            app.VectorColorDropDown.Items = {'Black', 'Red', 'White', 'Blue'};
            app.VectorColorDropDown.ItemsData = {'k', 'r', 'w', 'b'};
            app.VectorColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorColorDropDownValueChanged, true);
            app.VectorColorDropDown.Value = 'k';
            app.MaxinViewTextArea_2Label = uilabel(app.GridLayout3);
            app.MaxinViewTextArea_2Label.Text = 'Max in View : ';
            app.MaxinViewTextArea_2 = uitextarea(app.GridLayout3);
            app.MaxinViewTextArea_2.Editable = 'off';
            app.MaxinViewTextArea_2.Value = {'NaN'};
            app.VectorVisibilitySwitch = uiswitch(app.GridLayout3, 'toggle');
            app.VectorVisibilitySwitch.Items = {'Hide', 'Show'};
            app.VectorVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @VectorVisibilitySwitchValueChanged, true);
            app.VectorVisibilitySwitch.Value = 'Show';
            app.VectorDeleteButton = uibutton(app.GridLayout3, 'push');
            app.VectorDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @VectorDeleteButtonPushed, true);
            app.VectorDeleteButton.Text = 'Delete';
            app.ScalarPropertiesPanel = uipanel(app.GridLayout);
            app.ScalarPropertiesPanel.Title = 'Scalar Properties';
            app.GridLayout4 = uigridlayout(app.ScalarPropertiesPanel);
            app.ColormapLabel = uilabel(app.GridLayout4);
            app.ColormapLabel.Text = 'Colormap : ';
            app.ColormapDropDown = uidropdown(app.GridLayout4);
            app.ColormapDropDown.Items = {'jet', 'noaa_cmap', 'parula', 'GMT_jet_x20.act', ''};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'jet';
            app.NumColorsLabel = uilabel(app.GridLayout4);
            app.NumColorsLabel.Text = 'Num Colors : ';
            app.NumColorsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumColorsEditField.Limits = [2 256];
            app.NumColorsEditField.RoundFractionalValues = 'on';
            app.NumColorsEditField.ValueDisplayFormat = '%d';
            app.NumColorsEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.NumColorsEditField.Value = 24;
            app.MaxinViewTextAreaLabel = uilabel(app.GridLayout4);
            app.MaxinViewTextAreaLabel.Text = 'Max in View : ';
            app.MaxinViewTextArea = uitextarea(app.GridLayout4);
            app.MaxinViewTextArea.Editable = 'off';
            app.MaxinViewTextArea.Value = {'NaN'};
            app.MininViewTextAreaLabel = uilabel(app.GridLayout4);
            app.MininViewTextAreaLabel.Text = 'Min in View : ';
            app.MininViewTextArea = uitextarea(app.GridLayout4);
            app.MininViewTextArea.Editable = 'off';
            app.MininViewTextArea.Value = {'NaN'};
            app.ColorMinEditField_2Label = uilabel(app.GridLayout4);
            app.ColorMinEditField_2Label.Text = 'Color Min : ';
            app.ColorMinEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.ColorMinEditField_2.ValueDisplayFormat = '%8.2g';
            app.ColorMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorMaxEditFieldValueChanged, true);
            app.ColorMinEditField_2.Value = -1;
            app.ColorMaxEditField_2Label = uilabel(app.GridLayout4);
            app.ColorMaxEditField_2Label.Text = 'Color Max : ';
            app.ColorMaxEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.ColorMaxEditField_2.ValueDisplayFormat = '%8.2g';
            app.ColorMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorMaxEditFieldValueChanged, true);
            app.ColorMaxEditField_2.Value = 1;
            app.AutoColorOffButton = uibutton(app.GridLayout4, 'state');
            app.AutoColorOffButton.ValueChangedFcn = createCallbackFcn(app, @AutoColorOffButtonValueChanged, true);
            app.AutoColorOffButton.Text = 'Auto Color Off';
            app.AutoColorOffButton.Value = true;
            app.SurfaceVisibilitySwitch = uiswitch(app.GridLayout4, 'toggle');
            app.SurfaceVisibilitySwitch.Items = {'Hide', 'Show'};
            app.SurfaceVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @SurfaceVisibilitySwitchValueChanged, true);
            app.SurfaceVisibilitySwitch.Value = 'Show';
            app.SurfaceDeleteButton = uibutton(app.GridLayout4, 'push');
            app.SurfaceDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceDeleteButtonPushed, true);
            app.SurfaceDeleteButton.Text = 'Delete';
            app.MessagesLabel = uilabel(app.GridLayout);
            app.MessagesLabel.Text = {'Messages :'; ''};
            app.CurrentSolutionTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentSolutionTextAreaLabel.Text = 'Current Solution : ';
            app.MessagesTextArea = uitextarea(app.GridLayout);
            app.CurrentSolutionTextArea = uitextarea(app.GridLayout);
            app.GridPanel = uipanel(app.GridLayout);
            app.GridPanel.Title = 'Grid';
            app.GridLayout2 = uigridlayout(app.GridPanel);
            app.ElementsLabel = uilabel(app.GridLayout2);
            app.ElementsLabel.Text = 'Elements :';
            app.ElementsSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.ElementsSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementsButtonValueChanged2, true);
            app.ElementsSwitch.Tooltip = {'toggle elements on/off'};
            app.ContoursLabel = uilabel(app.GridLayout2);
            app.ContoursLabel.Text = 'Contours :';
            app.ContoursSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ContoursSwitch.ValueChangedFcn = createCallbackFcn(app, @ContoursSwitchValueChanged, true);
            app.ContoursSwitch.Value = 'On';
            app.NumNodesSwitchLabel = uilabel(app.GridLayout2);
            app.NumNodesSwitchLabel.Text = 'Num Nodes :';
            app.NumNodesSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NumNodesSwitch.ValueChangedFcn = createCallbackFcn(app, @NumNodesSwitchValueChanged, true);
            app.NumElemsSwitchLabel = uilabel(app.GridLayout2);
            app.NumElemsSwitchLabel.Text = 'Num Elems :';
            app.NumElemsSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NumElemsSwitch.ValueChangedFcn = createCallbackFcn(app, @NumElemsSwitchValueChanged, true);
            app.NumBathySwitchLabel = uilabel(app.GridLayout2);
            app.NumBathySwitchLabel.Text = 'Num Bathy :';
            app.NumBathySwitch = uiswitch(app.GridLayout2, 'toggle');
            app.NumBathySwitch.ValueChangedFcn = createCallbackFcn(app, @NumBathySwitchValueChanged, true);
            app.DepthValuesEditField = uieditfield(app.GridLayout2, 'text');
            app.DepthValuesEditField.ValueChangedFcn = createCallbackFcn(app, @DepthValuesEditFieldValueChanged, true);
            app.DepthValuesEditField.Tooltip = {'Enter topo/bathy values to contour.  Toggle Contours to erase.'};
            app.DepthValuesEditField.Value = '0 10  25  50  100 ';
            app.DepthValuesLabel = uilabel(app.GridLayout2);
            app.DepthValuesLabel.Text = 'Depth Values :';
            app.ClearContoursButton = uibutton(app.GridLayout2, 'push');
            app.ClearContoursButton.ButtonPushedFcn = createCallbackFcn(app, @ClearContoursButtonPushed, true);
            app.ClearContoursButton.Text = 'Clear Contours';
            app.GotoGlobalMaxButton = uibutton(app.GridLayout2, 'push');
            app.GotoGlobalMaxButton.ButtonPushedFcn = createCallbackFcn(app, @GotoGlobalMaxButtonPushed, true);
            app.GotoGlobalMaxButton.Tooltip = {'Zoom to global maximum at current time level'};
            app.GotoGlobalMaxButton.Text = 'Goto Global Max';
            app.GotoMaxInViewButton = uibutton(app.GridLayout2, 'push');
            app.GotoMaxInViewButton.Enable = 'off';
            app.GotoMaxInViewButton.Text = 'Goto Max In View';
            app.TimeSeriesPanel = uipanel(app.GridLayout);
            app.TimeSeriesPanel.Title = 'Time Series';
            app.GridLayout5 = uigridlayout(app.TimeSeriesPanel);
            app.SampleSolutionCheckBox = uicheckbox(app.GridLayout5);
            app.SampleSolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SampleSolutionCheckBoxValueChanged, true);
            app.SampleSolutionCheckBox.Text = 'Sample Solution';
            app.LegendLabel = uilabel(app.GridLayout5);
            app.LegendLabel.Text = 'Legend : ';
            app.LegendSwitch = uiswitch(app.GridLayout5, 'toggle');
            app.LegendSwitch.Items = {'Hide', 'Show'};
            app.LegendSwitch.ValueChangedFcn = createCallbackFcn(app, @LegendSwitchValueChanged, true);
            app.LegendSwitch.Value = 'Show';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status :';
            app.FrameControlPanel = uipanel(app.GridLayout);
            app.FrameControlPanel.Title = 'Frame Control';
            app.BackwardButton = uibutton(app.FrameControlPanel, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Icon = 'Left_64.png';
            app.BackwardButton.Enable = 'off';
            app.BackwardButton.Text = '';
            app.ForwardButton = uibutton(app.FrameControlPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Icon = 'Right_64.png';
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = '';
            app.GOButton = uibutton(app.FrameControlPanel, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @GOButtonValueChanged, true);
            app.GOButton.Enable = 'off';
            app.GOButton.Text = 'GO';
            app.TimeStrideEditFieldLabel = uilabel(app.FrameControlPanel);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride : ';
            app.TimeStrideEditField = uieditfield(app.FrameControlPanel, 'numeric');
            app.TimeStrideEditField.Limits = [1 Inf];
            app.TimeStrideEditField.RoundFractionalValues = 'on';
            app.TimeStrideEditField.ValueDisplayFormat = '%.0f';
            app.TimeStrideEditField.Value = 1;
            app.CurrentIndexEditFieldLabel = uilabel(app.FrameControlPanel);
            app.CurrentIndexEditFieldLabel.Text = 'Current Index : ';
            app.CurrentIndexEditField = uieditfield(app.FrameControlPanel, 'numeric');
            app.CurrentIndexEditField.Limits = [1 Inf];
            app.CurrentIndexEditField.RoundFractionalValues = 'on';
            app.CurrentIndexEditField.ValueDisplayFormat = '%.0f';
            app.CurrentIndexEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentIndexEditFieldValueChanged, true);
            app.CurrentIndexEditField.Value = 1;
            app.TImeIndexPanel = uipanel(app.GridLayout);
            app.TimeIndexLabel = uilabel(app.TImeIndexPanel);
            app.TimeIndexLabel.Text = 'Time Index : ';
            app.TimeIndexSlider = uislider(app.TImeIndexPanel);
            app.TimeIndexSlider.Limits = [1 100];
            app.TimeIndexSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderIndexEditFieldValueChanged, true);
            app.TimeIndexSlider.Value = 1;
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridNameTextAreaLabel = uilabel(app.Panel_2);
            app.GridNameTextAreaLabel.Text = 'Grid Name :';
            app.GridNameTextArea = uitextarea(app.Panel_2);
            app.GridNameTextArea.Editable = 'off';
            app.GridNameTextArea.Value = {'<>'};
            app.elementsTextAreaLabel = uilabel(app.Panel_2);
            app.elementsTextAreaLabel.Text = '# elements :';
            app.elementsTextArea = uitextarea(app.Panel_2);
            app.elementsTextArea.Editable = 'off';
            app.elementsTextArea.Value = {'<>'};
            app.nodesLabel = uilabel(app.Panel_2);
            app.nodesLabel.Text = '# nodes :';
            app.nodesTextArea = uitextarea(app.Panel_2);
            app.nodesTextArea.Editable = 'off';
            app.nodesTextArea.Value = {'<>'};
            app.TimeStepEditFieldLabel = uilabel(app.Panel_2);
            app.TimeStepEditFieldLabel.Text = 'Time Step :';
            app.TimeStepEditField = uieditfield(app.Panel_2, 'text');
            app.TimeStepEditField.Value = '<>';
            app.RNDAYTextAreaLabel = uilabel(app.Panel_2);
            app.RNDAYTextAreaLabel.Text = 'RNDAY :';
            app.RNDAYTextArea = uitextarea(app.Panel_2);
            app.RNDAYTextArea.Value = {'<>'};
            app.StatusLamp = uilamp(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.LOADButton = uibutton(app.GridLayout6, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.AxisEqualButton = uibutton(app.GridLayout6, 'push');
            app.AxisEqualButton.ButtonPushedFcn = createCallbackFcn(app, @AxisEqualButtonPushed, true);
            app.AxisEqualButton.Text = 'Axis Equal';
            app.AxisTightButton = uibutton(app.GridLayout6, 'push');
            app.AxisTightButton.ButtonPushedFcn = createCallbackFcn(app, @AxisTightButtonPushed, true);
            app.AxisTightButton.Text = 'Axis Tight';
            app.DrawFrameButton = uibutton(app.GridLayout6, 'push');
            app.DrawFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DrawFrameButtonPushed, true);
            app.DrawFrameButton.Text = 'Draw Frame';
            app.GMapButton = uibutton(app.GridLayout6, 'push');
            app.GMapButton.ButtonPushedFcn = createCallbackFcn(app, @GMapButtonValueChanged, true);
            app.GMapButton.Text = 'GMap';
            app.BrowserButton = uibutton(app.GridLayout6, 'push');
            app.BrowserButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.BrowserButton.Text = {'Browser'; ''};
            % Show the figure after all components are created
            app.ADCIRCAnimatorV09UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adcircAnimator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCIRCAnimatorV09UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCIRCAnimatorV09UIFigure)
        end
    end
end